classdef AutomatedApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Step                           matlab.ui.control.NumericEditField
        WavelengthStepLabel            matlab.ui.control.Label
        Readings                       matlab.ui.control.NumericEditField
        NumberofMeasurementsLabel      matlab.ui.control.Label
        BandGapCalculation             matlab.ui.control.CheckBox
        Transmission_lambda            matlab.ui.control.CheckBox
        Transmission_eV                matlab.ui.control.CheckBox
        FinalWavelength                matlab.ui.control.NumericEditField
        FinalWavelengthEditFieldLabel  matlab.ui.control.Label
        StartingWavelength             matlab.ui.control.NumericEditField
        StartingWavelengthEditFieldLabel  matlab.ui.control.Label
        RunScriptButton                matlab.ui.control.StateButton
        TextArea_6                     matlab.ui.control.TextArea
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RunScriptButton
        function RunScriptButtonValueChanged(app, event)
            %value = app.RunScriptButton.Value;
            s_lambda = app.StartingWavelength.Value;
            f_lambda = app.FinalWavelength.Value;
            t_eV = app.Transmission_eV.Value;
            t_lambda = app.Transmission_lambda.Value;
            t_calculation = app.BandGapCalculation.Value;
            e_Steps = app.Step.Value;
            e_Readings = app.Readings.Value;

            matrix1 = {'Starting Wavelength';'Final Wavelength';
                       'Step'; 'Readings';
                       'Transmission (lambda)'; 'Transmission (eV)';
                        'Calculation (eV)'};
            matrix2 = [s_lambda;f_lambda;e_Steps;e_Readings;t_eV;t_lambda;t_calculation];
            fid = fopen( 'Parameters.csv', 'w' );
            for jj = 1 : length( matrix1 )
                fprintf( fid, '%s,%d\n', matrix1{jj}, matrix2(jj) );
            end
            fclose( fid )

            uialert(app.UIFigure,'Upon pressing program will be run', ...
            'Program Information','Icon','info','CloseFcn','uiresume(app.UIFigure)')

            uiwait(app.UIFigure)

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Automated Optical Absorption'};
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Value = {'Parameters'};
            app.TextArea_6 = uitextarea(app.UIFigure);
            app.TextArea_6.Value = {'Graphs Plotted'};
            app.RunScriptButton = uibutton(app.UIFigure, 'state');
            app.RunScriptButton.ValueChangedFcn = createCallbackFcn(app, @RunScriptButtonValueChanged, true);
            app.RunScriptButton.Text = 'Run Script';
            app.StartingWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.StartingWavelengthEditFieldLabel.Text = 'Starting Wavelength';
            app.StartingWavelength = uieditfield(app.UIFigure, 'numeric');
            app.FinalWavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.FinalWavelengthEditFieldLabel.Text = 'Final Wavelength';
            app.FinalWavelength = uieditfield(app.UIFigure, 'numeric');
            app.FinalWavelength.Value = 1100;
            app.Transmission_eV = uicheckbox(app.UIFigure);
            app.Transmission_eV.Text = '       Transmission (eV)';
            app.Transmission_lambda = uicheckbox(app.UIFigure);
            app.Transmission_lambda.Text = '       Transmission (λ)';
            app.BandGapCalculation = uicheckbox(app.UIFigure);
            app.BandGapCalculation.Text = '       Band Gap Calculation (eV)';
            app.BandGapCalculation.Value = true;
            app.NumberofMeasurementsLabel = uilabel(app.UIFigure);
            app.NumberofMeasurementsLabel.Text = 'Readings per wavelength ';
            app.Readings = uieditfield(app.UIFigure, 'numeric');
            app.Readings.Value = 4;
            app.WavelengthStepLabel = uilabel(app.UIFigure);
            app.WavelengthStepLabel.Text = 'Wavelength Step';
            app.Step = uieditfield(app.UIFigure, 'numeric');
            app.Step.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AutomatedApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end